classdef GREG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MinTmEditField               matlab.ui.control.NumericEditField
        MinTmEditFieldLabel          matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        Sequence53TextArea           matlab.ui.control.TextArea
        Sequence53TextAreaLabel      matlab.ui.control.Label
        MaxbulgesEditField           matlab.ui.control.NumericEditField
        MaxbulgesEditFieldLabel      matlab.ui.control.Label
        MaxlooplengthEditField       matlab.ui.control.NumericEditField
        MaxlooplengthEditFieldLabel  matlab.ui.control.Label
        RunButton                    matlab.ui.control.Button
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end


    properties (Access = public)
        GQs % Description
        scores
        redoG4s
    end

    methods (Access = public)

    end

    methods (Access = private)


        function run_GReg(app)
            seq =  [app.Sequence53TextArea.Value{1:end}];
            
            ig = seq == 'G'| seq =='g';
            ia = seq == 'A'| seq =='a';
            it = seq == 'T'| seq =='t';
            ic = seq == 'C'| seq =='c';
            seq(ig) = 'G';
            seq(ia) = 'A';
            seq(it) = 'T';
            seq(ic) = 'C';
            
            if sum(ig+ia+it+ic) ~= length(seq)
                app.TextArea.Value= {'Sequence error - check sequence'};
            else
                seq2 = flip(seqrcomplement(seq));
                d1 = check_seq(seq=='G',app.GQs,app.scores,app.MaxlooplengthEditField.Value);
                d2 = check_seq(seq2=='G',app.GQs,app.scores,app.MaxlooplengthEditField.Value);
                app.TextArea.Value = {'Original Sequence (5-3)';'  '};
                convertG4CR(app,d1,seq,app.UIAxes)
                app.TextArea.Value = [app.TextArea.Value;'Complement (3-5)','  '];
                convertG4CR(app,d2,seq2,app.UIAxes_2)
            end

        end
        function con = convertG4CR(app,data,seq,ax)
            if ~isempty(data{2})
                cla(ax)
                hold(ax,'on')
                for i = 1:size(data{2},1)
                     L = (length(data{2}{i,1}));
                    pG = round(sum(data{2}{i,1}>=1)/L*100);
                    ntot = length(data{2}{i,4});
                    ntand = data{2}{i,5};
                    Tmin = round(min(data{2}{i,4}));
                    Tmed = round(median(data{2}{i,4}));
                    Tmax = round(max(data{2}{i,4}));
                    startInd = data{2}{i,2};
                    endInd = data{2}{i,3};
                    bar(ax,startInd+(0:1:length(data{2}{i})-1),data{2}{i})
                    tempLine = ['Start = ',num2str(startInd),': End = ',num2str(endInd),': Length (nt) = ',num2str(L),': G-Content (%) = ', num2str(pG),': Ntot = ',num2str(ntot),': Ntand = ',num2str(ntand),': Tm (min) = ',num2str(Tmin),': Tm (median) = ',num2str(Tmed),': Tm (max) = ',num2str(Tmax) ];
                    app.TextArea.Value =[app.TextArea.Value;tempLine;seq(startInd:endInd);'   '];
                end
                xlim(ax,[0,length(seq)+1])
                hold(ax,'off')
            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [G4,G4scores] = get_GQs(app.MaxlooplengthEditField.Value,app.MaxbulgesEditField.Value,app.MinTmEditField.Value);
            app.GQs = G4;
            app.scores = G4scores; 
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if app.redoG4s == 1
                            [G4,G4scores] = get_GQs(app.MaxlooplengthEditField.Value,app.MaxbulgesEditField.Value,app.MinTmEditField.Value);
                            app.GQs = G4;
                            app.scores = G4scores; 
                            app.redoG4s = 0;
            end
            
            run_GReg(app);
        end

        % Value changed function: MaxlooplengthEditField
        function MaxlooplengthEditFieldValueChanged(app, event)
            app.redoG4s = 1;

        end

        % Value changed function: MaxbulgesEditField
        function MaxbulgesEditFieldValueChanged(app, event)
            app.redoG4s = 1;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Sequence')
            xlabel(app.UIAxes, 'Nucleotide Position (5'' --> 3'')')
            ylabel(app.UIAxes, 'Multiplicity')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Complementary Sequence')
            xlabel(app.UIAxes_2, 'Nucleotide Position (3'' --> 5'')')
            ylabel(app.UIAxes_2, 'Multiplicity')
            zlabel(app.UIAxes_2, 'Z')
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.MaxlooplengthEditFieldLabel = uilabel(app.UIFigure);
            app.MaxlooplengthEditFieldLabel.Text = 'Max loop length';
            app.MaxlooplengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxlooplengthEditField.ValueChangedFcn = createCallbackFcn(app, @MaxlooplengthEditFieldValueChanged, true);
            app.MaxlooplengthEditField.Value = 7;
            app.MaxbulgesEditFieldLabel = uilabel(app.UIFigure);
            app.MaxbulgesEditFieldLabel.Text = 'Max # bulges';
            app.MaxbulgesEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxbulgesEditField.ValueChangedFcn = createCallbackFcn(app, @MaxbulgesEditFieldValueChanged, true);
            app.MaxbulgesEditField.Value = 4;
            app.Sequence53TextAreaLabel = uilabel(app.UIFigure);
            app.Sequence53TextAreaLabel.Text = 'Sequence (5'' --> 3'')';
            app.Sequence53TextArea = uitextarea(app.UIFigure);
            app.TextArea = uitextarea(app.UIFigure);
            app.MinTmEditFieldLabel = uilabel(app.UIFigure);
            app.MinTmEditFieldLabel.Text = 'Min Tm';
            app.MinTmEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinTmEditField.Value = 50;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GREG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end